classdef binarization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BinarizationUIFigure        matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        PickimageDropDownLabel      matlab.ui.control.Label
        PickimageDropDown           matlab.ui.control.DropDown
        BinarizeButton              matlab.ui.control.Button
        SelectButton                matlab.ui.control.Button
        Histogram                   matlab.ui.control.UIAxes
        ThresholdValue              matlab.ui.control.Slider
        BinarizationmethodDropDownLabel  matlab.ui.control.Label
        BinarizationmethodDropDown  matlab.ui.control.DropDown
        ChoosefolderButton          matlab.ui.control.Button
        ThresholdformanualbinarizationLabel  matlab.ui.control.Label
        RightPanel                  matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        ColorImage                  matlab.ui.control.Image
        GreyImage                   matlab.ui.control.Image
        BinaryImage                 matlab.ui.control.Image
        OriginalImageLabel          matlab.ui.control.Label
        GrayImageLabel              matlab.ui.control.Label
        BinarizedImageLabel         matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        cim % Color image
        bim % Binary image
        path % Path to image
        gim % Grey image
        folderName % Path to a folder with images
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            app.path = string(app.folderName)+"/"+app.PickimageDropDown.Value;
            if(contains(app.path, ".jpg"))
                app.cim = double(imread(app.path))/255;
                app.gim = ao_rgb2gray(app.cim);
                app.ColorImage.ImageSource = app.cim;
                app.GreyImage.ImageSource = repmat(app.gim, [1 1 3]);
                [count, binLocations] = imhist(app.gim);
                bar(app.Histogram, binLocations, count, 'BarWidth', 1);
                % histogram(app.Histogram, binLocations, count, "BarWidth", 1);
            end
        end

        % Button pushed function: BinarizeButton
        function BinarizeButtonPushed(app, event)
            if (isa(app.path, 'string'))
                thresh = 0;
                adap = false;
                switch (app.BinarizationmethodDropDown.Value)    
                    case "Otsu's method"
                        thresh = ao_otsuthresh(app.gim);
                    case "Manual"
                        thresh = app.ThresholdValue.Value;
                    case "Adaptive"
                        thresh = 0; ws = 30; C = 0.05; adap = true;
                        im_median = ao_medianfilt(app.gim, ws);
                        app.gim = im_median - app.gim - C;
                        
                end
                app.bim = ao_imbinarize(app.gim, thresh);
                if ~adap
                    tmp_bim = logical(app.bim);
                    tmp_bim = ~tmp_bim;
                    app.bim = double(tmp_bim);
                end
                app.BinaryImage.ImageSource = repmat(app.bim, [1 1 3]);
                if adap
                    app.gim = ao_rgb2gray(app.cim);
                end
            end
        end

        % Button pushed function: ChoosefolderButton
        function ChoosefolderButtonPushed(app, event)
            app.PickimageDropDown.Items = "No images in chosen folder";
            app.folderName = uigetdir;
            if(isa(app.folderName, 'char'))
                imageNames = dir(app.folderName);
                
                %generate Items string for dropdown menu containing image names
                for i = 1:length(imageNames)
                    imageBeg = i;
                    if (imageNames(i).name == '.')
                    else
                        if (contains(string(imageNames(i).name),".jpg"))
                            ItemsString = string(imageNames(i).name);
                            break
                        end
                    end
                end
                
                for i = (imageBeg + 1):length(imageNames)
                    if (imageNames(i).name == '.')
                    else
                        if (contains(string(imageNames(i).name),".jpg"))
                            ItemsString = [ItemsString string(imageNames(i).name)];
                        end
                    end
                end
                
                if(exist("ItemsString","var"))
                    app.PickimageDropDown.Items = ItemsString;
                end
                
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.BinarizationUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {294, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BinarizationUIFigure = uifigure('Visible', 'off');
            app.BinarizationUIFigure.Name = 'Binarization';
            app.BinarizationUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.BinarizationUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.PickimageDropDownLabel = uilabel(app.LeftPanel);
            app.PickimageDropDownLabel.Text = 'Pick image';
            app.PickimageDropDown = uidropdown(app.LeftPanel);
            app.PickimageDropDown.Items = {'No images in chosen folder'};
            app.PickimageDropDown.Value = 'No images in chosen folder';
            app.BinarizeButton = uibutton(app.LeftPanel, 'push');
            app.BinarizeButton.ButtonPushedFcn = createCallbackFcn(app, @BinarizeButtonPushed, true);
            app.BinarizeButton.Text = 'Binarize';
            app.SelectButton = uibutton(app.LeftPanel, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.Histogram = uiaxes(app.LeftPanel);
            title(app.Histogram, 'Image histogram')
            xlabel(app.Histogram, 'Pixel value')
            ylabel(app.Histogram, 'Amount')
            app.Histogram.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Histogram.XTickLabel = {'0'; '0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '1'};
            app.Histogram.YTick = [];
            app.Histogram.YTickLabel = '';
            app.ThresholdValue = uislider(app.LeftPanel);
            app.ThresholdValue.Limits = [0 1];
            app.BinarizationmethodDropDownLabel = uilabel(app.LeftPanel);
            app.BinarizationmethodDropDownLabel.Text = 'Binarization method';
            app.BinarizationmethodDropDown = uidropdown(app.LeftPanel);
            app.BinarizationmethodDropDown.Items = {'Otsu''s method', 'Manual', 'Adaptive'};
            app.BinarizationmethodDropDown.Value = 'Otsu''s method';
            app.ChoosefolderButton = uibutton(app.LeftPanel, 'push');
            app.ChoosefolderButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosefolderButtonPushed, true);
            app.ChoosefolderButton.Text = 'Choose folder';
            app.ThresholdformanualbinarizationLabel = uilabel(app.LeftPanel);
            app.ThresholdformanualbinarizationLabel.Text = 'Threshold for manual binarization';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.ColorImage = uiimage(app.GridLayout2);
            app.GreyImage = uiimage(app.GridLayout2);
            app.BinaryImage = uiimage(app.GridLayout2);
            app.OriginalImageLabel = uilabel(app.GridLayout2);
            app.OriginalImageLabel.Text = 'Original Image';
            app.GrayImageLabel = uilabel(app.GridLayout2);
            app.GrayImageLabel.Text = 'Gray Image';
            app.BinarizedImageLabel = uilabel(app.GridLayout2);
            app.BinarizedImageLabel.Text = 'Binarized Image';
            % Show the figure after all components are created
            app.BinarizationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = binarization

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BinarizationUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BinarizationUIFigure)
        end
    end
end